classdef TobiiDataImporter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainFigure               matlab.ui.Figure
        ExitButton               matlab.ui.control.Button
        LocalDataPanel           matlab.ui.container.Panel
        SessionContentsText      matlab.ui.control.TextArea
        SessionContentsLabel     matlab.ui.control.Label
        NewSessionButton         matlab.ui.control.Button
        NewSubjectButton         matlab.ui.control.Button
        NewStudyButton           matlab.ui.control.Button
        LocalFolderBrowseButton  matlab.ui.control.Button
        LocalFolderText          matlab.ui.control.Label
        LocalDataTree            matlab.ui.container.Tree
        TobiiDataPanel           matlab.ui.container.Panel
        ImportRecordingButton    matlab.ui.control.Button
        TobiiFolderBrowseButton  matlab.ui.control.Button
        TobiiFolderText          matlab.ui.control.Label
        TobiiDataTree            matlab.ui.container.Tree
    end

    
    properties (Access = private)
        settings % Description
    end
    
    methods (Access = private)
        
        function loadSettings(app)

            % get current path
            pt = fileparts(mfilename('fullpath'));

            settingsFileName = fullfile(pt,'tobiiGlasses2DataImportTool_settings.mat');
            if ~exist(settingsFileName,"file")
                settings.TobiiDataFolder = ''; %#ok<*ADPROP>
                settings.LocalDataFolder = '';
                save(settingsFileName,"settings");
            end
            
            load(settingsFileName,"settings");
            app.settings = settings;
        end
        
        function saveSettings(app)
            % get current path
            pt = fileparts(mfilename('fullpath'));

            settingsFileName = fullfile(pt,'tobiiGlasses2DataImportTool_settings.mat');
            settings = app.settings;
            save(settingsFileName,"settings");
        end
        
        function updateTobiiFolderTree(app)
            
            % disable and clear contents
            app.TobiiDataTree.Enable = false;
            nodes = app.TobiiDataTree.Children;
            nodes.delete;
            
            % disable import recording button
            app.ImportRecordingButton.Enable = false;
            
            % check data folder
            if isempty(app.settings.TobiiDataFolder) || ~exist(app.settings.TobiiDataFolder,'dir')
                return
            end
            
            % get all projects
            projectInformation = browse.scanProjects(app.settings.TobiiDataFolder);
            
            if isempty(projectInformation)
                return
            end
            
            % cycle through projects
            for ip = 1:length(projectInformation)
                
                % add project node
                nodeData = [];
                nodeData.type = 'project';
                nodeData.name = projectInformation(ip).name;
                nodeData.id = projectInformation(ip).id;
                nodeData.folder = projectInformation(ip).folder;
                
                description = [nodeData.name ' (' nodeData.id ')'];
                projectNode = uitreenode(app.TobiiDataTree,'Text',description,"NodeData",nodeData);
                
                % get recordings
                projectFolder = projectInformation(ip).folder;
                recordingInformation = browse.scanRecordings(projectFolder);
                
                % get all participants
                if ~isempty(recordingInformation)
                    participants = sort(unique({recordingInformation.participantName}));
                else
                    participants = {};
                end
                
                % cycle through participants
                for ipr = 1:length(participants)
                    
                    % get participant recording indices
                    participantInd = find(strcmp({recordingInformation.participantName},participants{ipr}));
                    
                    % get participant ID
                    IDs = {recordingInformation.participantID};
                    participantIDs = unique(IDs(participantInd));
                    participantID = participantIDs{1};
                    
                    % add participant node
                    nodeData = [];
                    nodeData.type = 'participant';
                    nodeData.name = participants{ipr};
                    nodeData.id = participantID;
                    
                    description = [nodeData.name ' (' nodeData.id ')'];
                    participantNode = uitreenode(projectNode,'Text',description,"NodeData",nodeData);
                    
                    % cycle through recordings
                    for ir = participantInd
                        
                        % add recording node
                        nodeData = [];
                        nodeData.type = 'recording';
                        nodeData.name = recordingInformation(ir).recordingName;
                        nodeData.id = recordingInformation(ir).recordingID;
                        nodeData.folder = recordingInformation(ir).folder;
                        nodeData.timestamp = recordingInformation(ir).recording.rec_created;
                        
                        description = [nodeData.name ' - ' nodeData.timestamp];
                        recordingNode = uitreenode(participantNode,'Text',description,"NodeData",nodeData);
                        
                    end
                    
                end
                
            end
            
            %  re-enable
            app.TobiiDataTree.Enable = true;
            
        end
        
        function updateLocalFolderTree(app)
            
            % disable and clear contents
            app.LocalDataTree.Enable = false;
            nodes = app.LocalDataTree.Children;
            nodes.delete;
            
            % disable new study and new session buttons
            app.NewSubjectButton.Enable = false;
            app.NewSessionButton.Enable = false;
            
            % reset session contents text
            app.SessionContentsText.Value = '';
            
            % check data folder
            if isempty(app.settings.LocalDataFolder) || ~exist(app.settings.LocalDataFolder,'dir')
                return
            end
            
            % get all studies
            studyFolders = dir(app.settings.LocalDataFolder);
            studyFolders(~[studyFolders.isdir]) = [];
            studyFolders = {studyFolders.name};
            studyFolders(strcmp(studyFolders,'.')) = [];
            studyFolders(strcmp(studyFolders,'..')) = [];
            
            % cycle through studies
            for ist = 1:length(studyFolders)
                
                % get study folder
                studyName = studyFolders{ist};
                studyFolder = fullfile(app.settings.LocalDataFolder,studyName);
                
                % add study node
                nodeData = [];
                nodeData.type = 'study';
                nodeData.name = studyName;
                nodeData.folder = studyFolder;
                studyNode = uitreenode(app.LocalDataTree,'Text',studyName,"NodeData",nodeData);
                
                % get all subjects
                subjectFolders = dir(studyFolder);
                subjectFolders(~[subjectFolders.isdir]) = [];
                subjectFolders = {subjectFolders.name};
                subjectFolders(strcmp(subjectFolders,'.')) = [];
                subjectFolders(strcmp(subjectFolders,'..')) = [];
                
                % cycle through subjects
                for isub = 1:length(subjectFolders)
                    
                    % get subject folder
                    subjectName = subjectFolders{isub};
                    subjectFolder = fullfile(studyFolder,subjectName);
                    
                    % add subject node
                    nodeData = [];
                    nodeData.type = 'subject';
                    nodeData.name = subjectName;
                    nodeData.folder = subjectFolder;
                    subjectNode = uitreenode(studyNode,'Text',subjectName,"NodeData",nodeData);
                    
                    % get all sessions
                    sessionFolders = dir(fullfile(subjectFolder,'SESSION_*'));
                    sessionFolders = {sessionFolders.name};
                    
                    % cycle through sessions
                    for iss = 1:length(sessionFolders)
                        
                        % get session folder
                        sessionName = sessionFolders{iss};
                        sessionFolder = fullfile(subjectFolder,sessionName);
                        
                        % get all recordings
                        recordingFolders = dir(fullfile(sessionFolder,'RECORDING_*'));
                        recordingFolders = {recordingFolders.name};
                        
                        % add session node
                        nodeData = [];
                        nodeData.type = 'session';
                        nodeData.name = sessionName;
                        nodeData.folder = sessionFolder;
                        nodeData.recordings = recordingFolders;
                        sessionNode = uitreenode(subjectNode,'Text',sessionName,"NodeData",nodeData);
                    end
                    
                end
                
                
            end
            
            %  re-enable
            app.LocalDataTree.Enable = true;
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % get current path
            pt = fileparts(mfilename('fullpath'));
            addpath(fullfile(pt,'functions'))
            
            % load application settings
            app.loadSettings();
            
            % update Tobii and local data folder text boxes
            if isempty(app.settings.TobiiDataFolder) || ~exist(app.settings.TobiiDataFolder,'dir')
                app.TobiiFolderText.Text = '';
            else
                [~, foldername] = fileparts(app.settings.TobiiDataFolder);
                app.TobiiFolderText.Text = foldername;
            end
            
            if isempty(app.settings.LocalDataFolder) || ~exist(app.settings.LocalDataFolder,'dir')
                app.LocalFolderText.Text = '';
            else
                [~, foldername] = fileparts(app.settings.LocalDataFolder);
                app.LocalFolderText.Text = foldername;
            end
            
            % update folder trees
            app.updateTobiiFolderTree();
            app.updateLocalFolderTree();
            
            % reset session contents text
            app.SessionContentsText.Value = '';
            
        end

        % Button pushed function: TobiiFolderBrowseButton
        function TobiiFolderBrowseButtonPushed(app, event)
            % open folder picker dialog
            folder = uigetdir('C:\','Select Tobii Data Folder');
            
            % get folder name
            if folder==0
                return
            end
            app.settings.TobiiDataFolder = folder;
            
            % update Tobii data folder text box
            if isempty(app.settings.TobiiDataFolder) || ~exist(app.settings.TobiiDataFolder,'dir')
                app.TobiiFolderText.Text = '';
            else
                [~, foldername] = fileparts(app.settings.TobiiDataFolder);
                if isempty(foldername)
                    foldername = app.settings.TobiiDataFolder;
                end
                app.TobiiFolderText.Text = foldername;
            end
            
            % save updated settings
            app.saveSettings();
            
            % update Tobii folder tree
            app.updateTobiiFolderTree();
        end

        % Button pushed function: LocalFolderBrowseButton
        function LocalFolderBrowseButtonPushed(app, event)
            % open folder picker dialog
            folder = uigetdir('C:\','Select Local Data Folder');
            
            % get folder name
            if folder==0
                return
            end
            app.settings.LocalDataFolder = folder;
            
            % update Tobii data folder text box
            if isempty(app.settings.LocalDataFolder) || ~exist(app.settings.LocalDataFolder,'dir')
                app.LocalFolderText.Text = '';
            else
                [~, foldername] = fileparts(app.settings.LocalDataFolder);
                app.LocalFolderText.Text = foldername;
            end
            
            % save updated settings
            app.saveSettings();
            
            % update local folder tree
            app.updateLocalFolderTree();
        end

        % Button pushed function: ImportRecordingButton
        function ImportRecordingButtonPushed(app, event)
            
            % make sure a recording and a session are selected
            if ~isempty(app.TobiiDataTree.SelectedNodes) && ~isempty(app.LocalDataTree.SelectedNodes) && strcmp(app.TobiiDataTree.SelectedNodes.NodeData.type,'recording') && strcmp(app.LocalDataTree.SelectedNodes.NodeData.type,'session')
                % all good
            else
                return
            end
            
            % get Tobii source folder
            sourceFolder = app.TobiiDataTree.SelectedNodes.NodeData.folder;
            
            % get destination folder
            sessionNode = app.LocalDataTree.SelectedNodes;
            sessionName = sessionNode.NodeData.name;
            sessionFolder = sessionNode.NodeData.folder;
            recordings = sessionNode.NodeData.recordings;
            
            % get subject and study names
            subjectNode = sessionNode.Parent;
            subjectName = subjectNode.NodeData.name;
            
            studyNode = subjectNode.Parent;
            studyName = studyNode.NodeData.name;
            
            
            if isempty(recordings)
                recordingID = 1;
            else
                recordings = sort(recordings);
                lastID = sscanf(recordings{end},'RECORDING_%03d');
                recordingID = lastID+1;
            end
            destinationFolder = fullfile(sessionFolder,sprintf('RECORDING_%03d',recordingID));
            
            if exist(destinationFolder,'dir')
                msgbox('Recording already exists','Import Recording','warn')
                return
            end
            
            % disable button
            app.ImportRecordingButton.Enable = false; drawnow
            
            % copy data
            copyfile(sourceFolder,destinationFolder);
            
            % load data
            recording = fileIO.loadRecording(destinationFolder);
            
            % save data in mat format
            save(fullfile(destinationFolder,'recording.mat'),'recording');
            
            % update local folder tree
            app.updateLocalFolderTree();
            
            % make sure current study expanded
            ind = find(strcmp({app.LocalDataTree.Children.Text},studyName),1,'first');
            studyNode = app.LocalDataTree.Children(ind);
            expand(studyNode);
            
            % make sure current subject is expanded
            ind = find(strcmp({studyNode.Children.Text},subjectName),1,'first');
            subjectNode = studyNode.Children(ind);
            expand(subjectNode);
            
            % make sure current subject is selected and expanded
            ind = find(strcmp({subjectNode.Children.Text},sessionName),1,'first');
            app.LocalDataTree.SelectedNodes = subjectNode.Children(ind);
            expand(app.LocalDataTree.SelectedNodes);
            
            LocalDataTreeSelectionChanged(app, event);
            
            % enable button
            app.ImportRecordingButton.Enable = true;
            
            
        end

        % Button pushed function: NewStudyButton
        function NewStudyButtonPushed(app, event)
            
            % request new study name
            answer = inputdlg('Enter study name','New Study',[1 40]);
            if isempty(answer)
                return
            end
            studyName = answer{1};
            
            % make sure this is a valid string (if it's a valid variable
            % name it should also be a valid folder name
            if ~isvarname(studyName)
                return
            end
            
            % construct full folder name
            studyFolder = fullfile(app.settings.LocalDataFolder,studyName);
            
            % check if folder already exists
            if exist(studyFolder,'dir')
                msgbox('Study already exists','New Study','warn')
                return
            else
                % make new study folder
                mkdir(studyFolder);
                
                % update local folder tree
                app.updateLocalFolderTree();
                
                % make sure current study is selected and expanded
                ind = find(strcmp({app.LocalDataTree.Children.Text},studyName),1,'first');
                app.LocalDataTree.SelectedNodes = app.LocalDataTree.Children(ind);
                expand(app.LocalDataTree.SelectedNodes);
                
                LocalDataTreeSelectionChanged(app, event);
            end
            
        end

        % Button pushed function: NewSubjectButton
        function NewSubjectButtonPushed(app, event)
            
            selectedNode = app.LocalDataTree.SelectedNodes;
            if ~isfield(selectedNode.NodeData,'type')
                return
            end
            
            % get selected study
            studyName = '';
            studyFolder = '';
            switch selectedNode.NodeData.type
                case 'study'
                    studyName = selectedNode.NodeData.name;
                    studyFolder = selectedNode.NodeData.folder;
                case 'subject'
                    studyNode = selectedNode.Parent;
                    studyName = studyNode.NodeData.name;
                    studyFolder = studyNode.NodeData.folder;
                case 'session'
                    subjectNode = selectedNode.Parent;
                    studyNode = subjectNode.Parent;
                    studyName = studyNode.NodeData.name;
                    studyFolder = studyNode.NodeData.folder;
            end
            
            if isempty(studyName) || isempty(studyFolder)
                return
            end
            
            % get all subjects in current study
            subjectNames = dir(fullfile(studyFolder,[studyName '_*']));
            subjectNames(~[subjectNames.isdir]) = [];
            subjectNames = {subjectNames.name};
            
            % get ID of next subject
            if isempty(subjectNames)
                nextID = 1;
            else
                subjectNames = sort(subjectNames);
                lastID = sscanf(subjectNames{end},[studyName '_%03d']);
                nextID = lastID+1;
            end
            
            newSubjectName = sprintf([studyName '_%03d'],nextID);
            newSubjectFolder = fullfile(studyFolder,newSubjectName);
            
            % create subject folder
            if exist(newSubjectFolder,'dir')
                return
            end
            mkdir(newSubjectFolder);
            
            % update local folder tree
            app.updateLocalFolderTree();
            
            % make sure current study expanded
            ind = find(strcmp({app.LocalDataTree.Children.Text},studyName),1,'first');
            studyNode = app.LocalDataTree.Children(ind);
            expand(studyNode);
            
            % make sure current subject is selected and expanded
            ind = find(strcmp({studyNode.Children.Text},newSubjectName),1,'first');
            app.LocalDataTree.SelectedNodes = studyNode.Children(ind);
            expand(app.LocalDataTree.SelectedNodes);
            
            LocalDataTreeSelectionChanged(app, event);
            
        end

        % Button pushed function: NewSessionButton
        function NewSessionButtonPushed(app, event)
            
            selectedNode = app.LocalDataTree.SelectedNodes;
            if ~isfield(selectedNode.NodeData,'type')
                return
            end
            
            % get selected study
            studyName = '';
            studyFolder = '';
            subjectName = '';
            subjectFolder = '';
            switch selectedNode.NodeData.type
                case 'study'
                    return
                case 'subject'
                    studyNode = selectedNode.Parent;
                    studyName = studyNode.NodeData.name;
                    studyFolder = studyNode.NodeData.folder;
                    subjectName = selectedNode.NodeData.name;
                    subjectFolder = selectedNode.NodeData.folder;
                case 'session'
                    subjectNode = selectedNode.Parent;
                    studyNode = subjectNode.Parent;
                    studyName = studyNode.NodeData.name;
                    studyFolder = studyNode.NodeData.folder;
                    subjectName = subjectNode.NodeData.name;
                    subjectFolder = subjectNode.NodeData.folder;
            end
            
            if isempty(studyName) || isempty(studyFolder)
                return
            end
            if isempty(subjectName) || isempty(subjectFolder)
                return
            end
            
            % get all sessions of current subject
            sessionNames = dir(fullfile(subjectFolder,'SESSION_*'));
            sessionNames(~[sessionNames.isdir]) = [];
            sessionNames = {sessionNames.name};
            
            % get ID of next subject
            if isempty(sessionNames)
                nextID = 1;
            else
                sessionNames = sort(sessionNames);
                lastID = sscanf(sessionNames{end},'SESSION_%03d');
                nextID = lastID+1;
            end
            
            newSessionName = sprintf('SESSION_%03d',nextID);
            newSessionFolder = fullfile(subjectFolder,newSessionName);
            
            % create session folder
            if exist(newSessionFolder,'dir')
                return
            end
            mkdir(newSessionFolder);
            
            % update local folder tree
            app.updateLocalFolderTree();
            
            % make sure current study expanded
            ind = find(strcmp({app.LocalDataTree.Children.Text},studyName),1,'first');
            studyNode = app.LocalDataTree.Children(ind);
            expand(studyNode);
            
            % make sure current subject is expanded
            ind = find(strcmp({studyNode.Children.Text},subjectName),1,'first');
            subjectNode = studyNode.Children(ind);
            expand(subjectNode);
            
            % make sure current subject is selected and expanded
            ind = find(strcmp({subjectNode.Children.Text},newSessionName),1,'first');
            app.LocalDataTree.SelectedNodes = subjectNode.Children(ind);
            expand(app.LocalDataTree.SelectedNodes);
            
            LocalDataTreeSelectionChanged(app, event);
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            mainFigureCloseRequest(app, event)
        end

        % Close request function: mainFigure
        function mainFigureCloseRequest(app, event)
            delete(app)
        end

        % Selection changed function: LocalDataTree
        function LocalDataTreeSelectionChanged(app, event)
            
            % disable new study and new session buttons
            app.NewSubjectButton.Enable = false;
            app.NewSessionButton.Enable = false;
            
            % get selected node
            selectedNodes = app.LocalDataTree.SelectedNodes;
            
            % make sure node data exists
            if ~isprop(selectedNodes,'NodeData')
                return
            end
            if ~isfield(selectedNodes.NodeData,'type')
                return
            end
            
            switch selectedNodes.NodeData.type
                case 'study'
                    app.NewSubjectButton.Enable = true;
                    app.SessionContentsText.Value = '';
                case 'subject'
                    app.NewSubjectButton.Enable = true;
                    app.NewSessionButton.Enable = true;
                    app.SessionContentsText.Value = '';
                case 'session'
                    app.NewSubjectButton.Enable = true;
                    app.NewSessionButton.Enable = true;
                    
                    recordings = selectedNodes.NodeData.recordings;
                    if isempty(recordings)
                        app.SessionContentsText.Value = '';
                    else
                        app.SessionContentsText.Value = recordings;
                    end
            end
            
            if ~isempty(app.TobiiDataTree.SelectedNodes) && ~isempty(app.LocalDataTree.SelectedNodes) && strcmp(app.TobiiDataTree.SelectedNodes.NodeData.type,'recording') && strcmp(app.LocalDataTree.SelectedNodes.NodeData.type,'session')
                app.ImportRecordingButton.Enable = true;
            else
                app.ImportRecordingButton.Enable = false;
            end
        end

        % Selection changed function: TobiiDataTree
        function TobiiDataTreeSelectionChanged(app, event)
            
            % disable new study and new session buttons
            app.ImportRecordingButton.Enable = false;
            
            % get selected node
            selectedNodes = app.TobiiDataTree.SelectedNodes;
            
            % make sure node data exists
            if ~isprop(selectedNodes,'NodeData')
                return
            end
            if ~isfield(selectedNodes.NodeData,'type')
                return
            end
            
            if ~isempty(app.TobiiDataTree.SelectedNodes) && ~isempty(app.LocalDataTree.SelectedNodes) && strcmp(app.TobiiDataTree.SelectedNodes.NodeData.type,'recording') && strcmp(app.LocalDataTree.SelectedNodes.NodeData.type,'session')
                app.ImportRecordingButton.Enable = true;
            else
                app.ImportRecordingButton.Enable = false;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainFigure = uifigure('Visible', 'off');
            app.mainFigure.Name = 'Tobii Glasses 2 Data Import Tool';
            app.mainFigure.CloseRequestFcn = createCallbackFcn(app, @mainFigureCloseRequest, true);
            app.TobiiDataPanel = uipanel(app.mainFigure);
            app.TobiiDataPanel.Title = 'Tobii Glasses 2 SD Card';
            app.TobiiDataTree = uitree(app.TobiiDataPanel);
            app.TobiiDataTree.SelectionChangedFcn = createCallbackFcn(app, @TobiiDataTreeSelectionChanged, true);
            app.TobiiFolderText = uilabel(app.TobiiDataPanel);
            app.TobiiFolderText.Text = '';
            app.TobiiFolderBrowseButton = uibutton(app.TobiiDataPanel, 'push');
            app.TobiiFolderBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @TobiiFolderBrowseButtonPushed, true);
            app.TobiiFolderBrowseButton.Text = 'Browse';
            app.ImportRecordingButton = uibutton(app.TobiiDataPanel, 'push');
            app.ImportRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @ImportRecordingButtonPushed, true);
            app.ImportRecordingButton.Text = 'Import Recording';
            app.LocalDataPanel = uipanel(app.mainFigure);
            app.LocalDataPanel.Title = 'Local Data Folder';
            app.LocalDataTree = uitree(app.LocalDataPanel);
            app.LocalDataTree.SelectionChangedFcn = createCallbackFcn(app, @LocalDataTreeSelectionChanged, true);
            app.LocalFolderText = uilabel(app.LocalDataPanel);
            app.LocalFolderText.Text = '';
            app.LocalFolderBrowseButton = uibutton(app.LocalDataPanel, 'push');
            app.LocalFolderBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @LocalFolderBrowseButtonPushed, true);
            app.LocalFolderBrowseButton.Text = 'Browse';
            app.NewStudyButton = uibutton(app.LocalDataPanel, 'push');
            app.NewStudyButton.ButtonPushedFcn = createCallbackFcn(app, @NewStudyButtonPushed, true);
            app.NewStudyButton.Text = 'New Study';
            app.NewSubjectButton = uibutton(app.LocalDataPanel, 'push');
            app.NewSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @NewSubjectButtonPushed, true);
            app.NewSubjectButton.Text = 'New Subject';
            app.NewSessionButton = uibutton(app.LocalDataPanel, 'push');
            app.NewSessionButton.ButtonPushedFcn = createCallbackFcn(app, @NewSessionButtonPushed, true);
            app.NewSessionButton.Text = 'New Session';
            app.SessionContentsLabel = uilabel(app.LocalDataPanel);
            app.SessionContentsLabel.Text = 'Session Contents';
            app.SessionContentsText = uitextarea(app.LocalDataPanel);
            app.SessionContentsText.Editable = 'off';
            app.ExitButton = uibutton(app.mainFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.mainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TobiiDataImporter

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.mainFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.mainFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainFigure)
        end
    end
end